classdef Lab02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        DarkerButton    matlab.ui.control.Button
        BrighterButton  matlab.ui.control.Button
        GrayButton      matlab.ui.control.Button
        OpenButton      matlab.ui.control.Button
        CloseButton     matlab.ui.control.Button
        UIAxes4         matlab.ui.control.UIAxes
        UIAxes3         matlab.ui.control.UIAxes
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global image1;
            [file1,path1]=uigetfile('*.jpg;*.png;*.bmp','Load the image');
            image1 = imread([path1,file1]);
            imshow(image1,'Parent',app.UIAxes);
        end

        % Button pushed function: GrayButton
        function GrayButtonPushed(app, event)
            global image1;
            global image2;
            image2 = rgb2gray(image1);
            imshow(image2,'Parent',app.UIAxes2);
        end

        % Button pushed function: BrighterButton
        function BrighterButtonPushed(app, event)
            global image2;
            image3 = image2 + 50;
            imshow(image3,'Parent',app.UIAxes3);
        end

        % Button pushed function: DarkerButton
        function DarkerButtonPushed(app, event)
            global image2;
            image4 = image2 - 50;
            imshow(image4,'Parent',app.UIAxes4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Gray')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Brighter')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Darker')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.GrayButton = uibutton(app.UIFigure, 'push');
            app.GrayButton.ButtonPushedFcn = createCallbackFcn(app, @GrayButtonPushed, true);
            app.GrayButton.Text = 'Gray';
            app.BrighterButton = uibutton(app.UIFigure, 'push');
            app.BrighterButton.ButtonPushedFcn = createCallbackFcn(app, @BrighterButtonPushed, true);
            app.BrighterButton.Text = 'Brighter';
            app.DarkerButton = uibutton(app.UIFigure, 'push');
            app.DarkerButton.ButtonPushedFcn = createCallbackFcn(app, @DarkerButtonPushed, true);
            app.DarkerButton.Text = 'Darker';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end