classdef Lab03 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LogButton            matlab.ui.control.Button
        LaplacianButton      matlab.ui.control.Button
        SobelButton          matlab.ui.control.Button
        PrewittButton        matlab.ui.control.Button
        GaussianButton       matlab.ui.control.Button
        AvarageButton        matlab.ui.control.Button
        NoiseGaussianButton  matlab.ui.control.Button
        NoiseSPButton        matlab.ui.control.Button
        OpenButton           matlab.ui.control.Button
        CloseButton          matlab.ui.control.Button
        UIAxes6              matlab.ui.control.UIAxes
        UIAxes5              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global original_image;
            [file1,path1] = uigetfile('*.jpg;*.png;*.bmp');
            original_image = imread([path1,file1]);
            imshow(original_image,'Parent',app.UIAxes);
        end

        % Button pushed function: NoiseSPButton
        function NoiseSPButtonPushed(app, event)
            global original_image;
            global salt_image;
            salt_image = imnoise(original_image,"salt & pepper",0.3);
            imshow(salt_image,'Parent',app.UIAxes2);
        end

        % Button pushed function: NoiseGaussianButton
        function NoiseGaussianButtonPushed(app, event)
            global original_image;
            global gaus_image;
            gaus_image = imnoise(original_image,"gaussian",0.3);
            imshow(gaus_image,'Parent',app.UIAxes3);
        end

        % Button pushed function: AvarageButton
        function AvarageButtonPushed(app, event)
            global original_image
            global gaus_image
            global salt_image

            av_original = original_image
            av_gaus = gaus_image
            av_salt = salt_image

            filter = fspecial('average',3);
            av_original = imfilter(av_original,filter)
            av_gaus = imfilter(av_gaus,filter)
            av_salt = imfilter(av_salt,filter)
            imshow(av_original,'Parent',app.UIAxes4)
            imshow(av_salt,'Parent',app.UIAxes5)
            imshow(av_gaus,'Parent',app.UIAxes6)
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            global original_image
            global gaus_image
            global salt_image

            av_original = original_image
            av_gaus = gaus_image
            av_salt = salt_image

            filter = fspecial('gaussian',3);
            av_original = imfilter(av_original,filter)
            av_gaus = imfilter(av_gaus,filter)
            av_salt = imfilter(av_salt,filter)
            imshow(av_original,'Parent',app.UIAxes4)
            imshow(av_salt,'Parent',app.UIAxes5)
            imshow(av_gaus,'Parent',app.UIAxes6)
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            global original_image
            global gaus_image
            global salt_image

            av_original = original_image
            av_gaus = gaus_image
            av_salt = salt_image

            filter = fspecial('prewitt');
            av_original = imfilter(av_original,filter)
            av_gaus = imfilter(av_gaus,filter)
            av_salt = imfilter(av_salt,filter)
            imshow(av_original,'Parent',app.UIAxes4)
            imshow(av_salt,'Parent',app.UIAxes5)
            imshow(av_gaus,'Parent',app.UIAxes6)
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            global original_image
            global gaus_image
            global salt_image

            av_original = original_image
            av_gaus = gaus_image
            av_salt = salt_image

            filter = fspecial('sobel');
            av_original = imfilter(av_original,filter)
            av_gaus = imfilter(av_gaus,filter)
            av_salt = imfilter(av_salt,filter)
            imshow(av_original,'Parent',app.UIAxes4)
            imshow(av_salt,'Parent',app.UIAxes5)
            imshow(av_gaus,'Parent',app.UIAxes6)
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            global original_image
            global gaus_image
            global salt_image

            av_original = original_image
            av_gaus = gaus_image
            av_salt = salt_image

            filter = fspecial('laplacian');
            av_original = imfilter(av_original,filter)
            av_gaus = imfilter(av_gaus,filter)
            av_salt = imfilter(av_salt,filter)
            imshow(av_original,'Parent',app.UIAxes4)
            imshow(av_salt,'Parent',app.UIAxes5)
            imshow(av_gaus,'Parent',app.UIAxes6)
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            global original_image
            global gaus_image
            global salt_image

            av_original = original_image
            av_gaus = gaus_image
            av_salt = salt_image

            filter = fspecial('log');
            av_original = imfilter(av_original,filter)
            av_gaus = imfilter(av_gaus,filter)
            av_salt = imfilter(av_salt,filter)
            imshow(av_original,'Parent',app.UIAxes4)
            imshow(av_salt,'Parent',app.UIAxes5)
            imshow(av_gaus,'Parent',app.UIAxes6)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Noise S&P')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Noise Gauss')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Original')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'S&P')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Gauss')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.NoiseSPButton = uibutton(app.UIFigure, 'push');
            app.NoiseSPButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseSPButtonPushed, true);
            app.NoiseSPButton.Text = 'Noise S&P';
            app.NoiseGaussianButton = uibutton(app.UIFigure, 'push');
            app.NoiseGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseGaussianButtonPushed, true);
            app.NoiseGaussianButton.Text = 'Noise Gaussian';
            app.AvarageButton = uibutton(app.UIFigure, 'push');
            app.AvarageButton.ButtonPushedFcn = createCallbackFcn(app, @AvarageButtonPushed, true);
            app.AvarageButton.Text = 'Avarage';
            app.GaussianButton = uibutton(app.UIFigure, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.PrewittButton = uibutton(app.UIFigure, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.UIFigure, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.LaplacianButton = uibutton(app.UIFigure, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab03

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end