classdef Lab04 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AngleEditField          matlab.ui.control.NumericEditField
        AngleEditFieldLabel     matlab.ui.control.Label
        RotationButton          matlab.ui.control.Button
        ChannelEditField        matlab.ui.control.NumericEditField
        ChannelEditFieldLabel   matlab.ui.control.Label
        ColumnEditField         matlab.ui.control.NumericEditField
        ColumnEditFieldLabel    matlab.ui.control.Label
        RowEditField            matlab.ui.control.NumericEditField
        RowEditFieldLabel       matlab.ui.control.Label
        ScaleButton             matlab.ui.control.Button
        CutOutQuarterButton     matlab.ui.control.Button
        FlipHorizontallyButton  matlab.ui.control.Button
        FlipVerticallyButton    matlab.ui.control.Button
        NormalizationButton     matlab.ui.control.Button
        DarkerButton            matlab.ui.control.Button
        BrighterButton          matlab.ui.control.Button
        RestoreButton           matlab.ui.control.Button
        OpenButton              matlab.ui.control.Button
        CloseButton             matlab.ui.control.Button
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global originalImage;
            global grayImage;
            global file;
            global path;
            [file,path] = uigetfile('*.jpg;*.png;*.bmp','Load the image');
            originalImage = imread([path,file]);
            recoverImage = originalImage;
            imshow(originalImage,'Parent',app.UIAxes);
            grayImage = rgb2gray(originalImage);
            imshow(grayImage,'Parent',app.UIAxes2);

        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            global originalImage;
            global grayImage;
            global file;
            global path;

            originalImage = imread([path,file]);
            imshow(originalImage,'Parent',app.UIAxes3);
            grayImage = rgb2gray(originalImage);
            imshow(grayImage,'Parent',app.UIAxes4);

        end

        % Button pushed function: BrighterButton
        function BrighterButtonPushed(app, event)
            global originalImage;
            global grayImage;
            originalImage = originalImage + 80;
            grayImage = grayImage + 80;
            imshow(originalImage,'Parent',app.UIAxes3);
            imshow(grayImage,'Parent',app.UIAxes4);
        end

        % Button pushed function: DarkerButton
        function DarkerButtonPushed(app, event)
            global originalImage;
            global grayImage;
            originalImage = originalImage - 80;
            grayImage = grayImage - 80;
            imshow(originalImage,'Parent',app.UIAxes3);
            imshow(grayImage,'Parent',app.UIAxes4);
        end

        % Button pushed function: NormalizationButton
        function NormalizationButtonPushed(app, event)
            global originalImage;
            global grayImage;
            originalImage = im2double(originalImage);
            nrOriginal = zeros(size(originalImage))
            for channel = 1:3
                nrOriginal(:,:,channel) = imadjust(originalImage(:,:,channel)) 
            end
            originalImage = nrOriginal
            imshow(originalImage,'Parent',app.UIAxes3);
            grayImage = imadjust(grayImage);
            imshow(grayImage,'Parent',app.UIAxes4);
        end

        % Button pushed function: FlipVerticallyButton
        function FlipVerticallyButtonPushed(app, event)
            global originalImage;
            global grayImage;
            originalImage = fliplr(originalImage)
            grayImage = fliplr(grayImage);
            imshow(originalImage,'Parent',app.UIAxes3);
            imshow(grayImage,'Parent',app.UIAxes4);
        end

        % Button pushed function: FlipHorizontallyButton
        function FlipHorizontallyButtonPushed(app, event)
            global originalImage;
            global grayImage;
            originalImage = flipud(originalImage)
            grayImage = flipud(grayImage);
            imshow(originalImage,'Parent',app.UIAxes3);
            imshow(grayImage,'Parent',app.UIAxes4);
        end

        % Button pushed function: CutOutQuarterButton
        function CutOutQuarterButtonPushed(app, event)
            global originalImage;
            global grayImage
            [rowSize,columnSize,channels] = size(originalImage);
            originalImage = originalImage(1:floor(rowSize / 2),1:(columnSize / 2),:);
            grayImage = grayImage(1:floor(rowSize / 2),1:(columnSize / 2));
            imshow(originalImage,'Parent',app.UIAxes3);
            imshow(grayImage,'Parent',app.UIAxes4);
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            global originalImage;
            global grayImage;
            rowSize = app.RowEditField.Value
            columSize  = app.ColumnEditField.Value
            channel = app.ChannelEditField.Value
            originalImage = imresize3(originalImage,[rowSize,columSize,channel]);
            grayImage = imresize(grayImage,[rowSize,columSize]);
            imshow(originalImage,'Parent',app.UIAxes3);
            imshow(grayImage,'Parent',app.UIAxes4);
        end

        % Button pushed function: RotationButton
        function RotationButtonPushed(app, event)
            global originalImage
            global grayImage
            angle = app.AngleEditField.Value;
            originalImage = imrotate(originalImage,angle);
            grayImage = imrotate(grayImage,angle);
            imshow(originalImage,'Parent',app.UIAxes3);
            imshow(grayImage,'Parent',app.UIAxes4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.RestoreButton = uibutton(app.UIFigure, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.BrighterButton = uibutton(app.UIFigure, 'push');
            app.BrighterButton.ButtonPushedFcn = createCallbackFcn(app, @BrighterButtonPushed, true);
            app.BrighterButton.Text = 'Brighter';
            app.DarkerButton = uibutton(app.UIFigure, 'push');
            app.DarkerButton.ButtonPushedFcn = createCallbackFcn(app, @DarkerButtonPushed, true);
            app.DarkerButton.Text = 'Darker';
            app.NormalizationButton = uibutton(app.UIFigure, 'push');
            app.NormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizationButtonPushed, true);
            app.NormalizationButton.Text = 'Normalization';
            app.FlipVerticallyButton = uibutton(app.UIFigure, 'push');
            app.FlipVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.FlipHorizontallyButton = uibutton(app.UIFigure, 'push');
            app.FlipHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.CutOutQuarterButton = uibutton(app.UIFigure, 'push');
            app.CutOutQuarterButton.ButtonPushedFcn = createCallbackFcn(app, @CutOutQuarterButtonPushed, true);
            app.CutOutQuarterButton.Text = 'Cut Out Quarter';
            app.ScaleButton = uibutton(app.UIFigure, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.RowEditFieldLabel = uilabel(app.UIFigure);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowEditField.Value = 50;
            app.ColumnEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnEditFieldLabel.Text = 'Column';
            app.ColumnEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnEditField.Value = 50;
            app.ChannelEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelEditField.Value = 3;
            app.RotationButton = uibutton(app.UIFigure, 'push');
            app.RotationButton.ButtonPushedFcn = createCallbackFcn(app, @RotationButtonPushed, true);
            app.RotationButton.Text = 'Rotation';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.Limits = [-360 360];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab04

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end