classdef Lab05 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BinaryCloseButton        matlab.ui.control.Button
        BinaryOpenButton         matlab.ui.control.Button
        DilationButton           matlab.ui.control.Button
        EroisonButton            matlab.ui.control.Button
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        BinarizationButton       matlab.ui.control.Button
        MEDFilterButton          matlab.ui.control.Button
        MinFIlterButton          matlab.ui.control.Button
        MaxFilterButton          matlab.ui.control.Button
        GrayButton               matlab.ui.control.Button
        OpenButton               matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        UIAxes6                  matlab.ui.control.UIAxes
        UIAxes5                  matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure)
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global originalImage;
            [file,path] = uigetfile('*.jpg;*.png;*.bmp','Load the image');
            originalImage = imread([path,file]);
            imshow(originalImage,'Parent',app.UIAxes);
        end

        % Button pushed function: GrayButton
        function GrayButtonPushed(app, event)
            global originalImage;
            global grayImage;
            grayImage = rgb2gray(originalImage)
            imshow(grayImage,'Parent',app.UIAxes2)
        end

        % Button pushed function: MaxFilterButton
        function MaxFilterButtonPushed(app, event)
            % Read the color image
            global originalImage
            global grayImage
            
            [m, n, ~] = size(originalImage);
            
            maxFilteredImage = uint8(zeros(m, n, 3));
            maxFilteredGrayImage = uint8(zeros(m,n));
            
            
            filterSize = 3;
            for i = 2:m-1
                for j = 2:n-1
                    neighborhoodR = originalImage(i-1:i+1, j-1:j+1, 1);
                    neighborhoodG = originalImage(i-1:i+1, j-1:j+1, 2);
                    neighborhoodB = originalImage(i-1:i+1, j-1:j+1, 3);
                    
                    maxValR = max(neighborhoodR(:));
                    maxValG = max(neighborhoodG(:));
                    maxValB = max(neighborhoodB(:));
                    
                    maxFilteredImage(i, j, 1) = maxValR;
                    maxFilteredImage(i, j, 2) = maxValG;
                    maxFilteredImage(i, j, 3) = maxValB;
                end
            end

            for i = 2:m-1
                for j = 2:n-1
                    neighborhood = grayImage(i-1:i+1, j-1:j+1, 1);
                    
                    maxVal = max(neighborhood(:));
                    
                    maxFilteredGrayImage(i, j) = maxVal;
                end
            end


            imshow(maxFilteredImage,'Parent',app.UIAxes3)
            imshow(maxFilteredGrayImage,'Parent',app.UIAxes4)

        end

        % Button pushed function: MinFIlterButton
        function MinFIlterButtonPushed(app, event)
            % Read the color image
            global originalImage
            global grayImage
            
            [m, n, ~] = size(originalImage);
            
            minFilteredImage = uint8(zeros(m, n, 3));
            minFilteredGrayImage = uint8(zeros(m,n));
            
            
            filterSize = 3;
            for i = 2:m-1
                for j = 2:n-1
                    neighborhoodR = originalImage(i-1:i+1, j-1:j+1, 1);
                    neighborhoodG = originalImage(i-1:i+1, j-1:j+1, 2);
                    neighborhoodB = originalImage(i-1:i+1, j-1:j+1, 3);
                    
                    minValR = min(neighborhoodR(:));
                    minValG = min(neighborhoodG(:));
                    minValB = min(neighborhoodB(:));
                    
                    minFilteredImage(i, j, 1) = minValR;
                    minFilteredImage(i, j, 2) = minValG;
                    minFilteredImage(i, j, 3) = minValB;
                end
            end

            for i = 2:m-1
                for j = 2:n-1
                    neighborhood = grayImage(i-1:i+1, j-1:j+1, 1);
                    
                    minVal = min(neighborhood(:));
                    
                    minFilteredGrayImage(i, j) = minVal;
                end
            end


            imshow(minFilteredImage,'Parent',app.UIAxes3)
            imshow(minFilteredGrayImage,'Parent',app.UIAxes4)
        end

        % Button pushed function: MEDFilterButton
        function MEDFilterButtonPushed(app, event)
            % Read the color image
            global originalImage
            global grayImage
            
            [m, n, ~] = size(originalImage);
            
            medFilteredImage = uint8(zeros(m, n, 3));
            medFilteredGrayImage = uint8(zeros(m,n));
            
            
            filterSize = 3;
            for i = 2:m-1
                for j = 2:n-1
                    neighborhoodR = originalImage(i-1:i+1, j-1:j+1, 1);
                    neighborhoodG = originalImage(i-1:i+1, j-1:j+1, 2);
                    neighborhoodB = originalImage(i-1:i+1, j-1:j+1, 3);
                    
                    medValR = median(neighborhoodR(:));
                    medValG = median(neighborhoodG(:));
                    medValB = median(neighborhoodB(:));
                    
                    medFilteredImage(i, j, 1) = medValR;
                    medFilteredImage(i, j, 2) = medValG;
                    medFilteredImage(i, j, 3) = medValB;
                end
            end

            for i = 2:m-1
                for j = 2:n-1
                    neighborhood = grayImage(i-1:i+1, j-1:j+1, 1);
                    
                    medVal = median(neighborhood(:));
                    
                    medFilteredGrayImage(i, j) = medVal;
                end
            end


            imshow(medFilteredImage,'Parent',app.UIAxes3)
            imshow(medFilteredGrayImage,'Parent',app.UIAxes4)
        end

        % Button pushed function: BinarizationButton
        function BinarizationButtonPushed(app, event)
            global originalImage
            global grayImage
            global binaryImage
            
            
            [m, n, ~] = size(originalImage);
            threshold = app.ThresholdEditField.Value

            binaryImage = uint8(zeros(m,n))
            for i = 1:m
                for j = 1:n
                    if grayImage(i,j) >= threshold
                        binaryImage(i,j) = 255;
                    else
                        binaryImage(i,j) = 0;
                    end
                    
                end

            end
            
            imshow(binaryImage,'Parent',app.UIAxes5);
        end

        % Button pushed function: EroisonButton
        function EroisonButtonPushed(app, event)
            global binaryImage
            global mImage
            
            se = strel('ball',5,5);
            binaryImage = imerode(binaryImage,se);
            
            imshow(binaryImage,'Parent',app.UIAxes6)
        end

        % Button pushed function: DilationButton
        function DilationButtonPushed(app, event)
            global binaryImage

            se = offsetstrel('ball',5,5);
            binaryImage = imdilate(binaryImage,se);
            
            imshow(binaryImage,'Parent',app.UIAxes6)
        end

        % Button pushed function: BinaryOpenButton
        function BinaryOpenButtonPushed(app, event)
            global binaryImage
            se = strel('disk',5);
            imshow(imopen(binaryImage,se),'Parent',app.UIAxes6)
        end

        % Button pushed function: BinaryCloseButton
        function BinaryCloseButtonPushed(app, event)
            global binaryImage
            se = strel('disk',5);
            imshow(imclose(binaryImage,se),'Parent',app.UIAxes6)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.GrayButton = uibutton(app.UIFigure, 'push');
            app.GrayButton.ButtonPushedFcn = createCallbackFcn(app, @GrayButtonPushed, true);
            app.GrayButton.Text = 'Gray';
            app.MaxFilterButton = uibutton(app.UIFigure, 'push');
            app.MaxFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MaxFilterButtonPushed, true);
            app.MaxFilterButton.Text = 'Max Filter';
            app.MinFIlterButton = uibutton(app.UIFigure, 'push');
            app.MinFIlterButton.ButtonPushedFcn = createCallbackFcn(app, @MinFIlterButtonPushed, true);
            app.MinFIlterButton.Text = 'Min FIlter';
            app.MEDFilterButton = uibutton(app.UIFigure, 'push');
            app.MEDFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MEDFilterButtonPushed, true);
            app.MEDFilterButton.Text = 'MED Filter';
            app.BinarizationButton = uibutton(app.UIFigure, 'push');
            app.BinarizationButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizationButtonPushed, true);
            app.BinarizationButton.Text = 'Binarization';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 255];
            app.ThresholdEditField.Value = 100;
            app.EroisonButton = uibutton(app.UIFigure, 'push');
            app.EroisonButton.ButtonPushedFcn = createCallbackFcn(app, @EroisonButtonPushed, true);
            app.EroisonButton.Text = 'Eroison';
            app.DilationButton = uibutton(app.UIFigure, 'push');
            app.DilationButton.ButtonPushedFcn = createCallbackFcn(app, @DilationButtonPushed, true);
            app.DilationButton.Text = 'Dilation';
            app.BinaryOpenButton = uibutton(app.UIFigure, 'push');
            app.BinaryOpenButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryOpenButtonPushed, true);
            app.BinaryOpenButton.Text = 'Binary Open';
            app.BinaryCloseButton = uibutton(app.UIFigure, 'push');
            app.BinaryCloseButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryCloseButtonPushed, true);
            app.BinaryCloseButton.Text = 'Binary Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab05

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end