classdef Lab07 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CompareMSERFButton  matlab.ui.control.Button
        CompareSURFButton   matlab.ui.control.Button
        CompareFREAKButton  matlab.ui.control.Button
        CompareSIFTButton   matlab.ui.control.Button
        OpenSmallButton     matlab.ui.control.Button
        CloseButton         matlab.ui.control.Button
        OpenButton          matlab.ui.control.Button
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global originalImage
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, 'Select Image File');
            originalImage = imread([path,file]);
            imshow(originalImage,'Parent',app.UIAxes);

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenSmallButton
        function OpenSmallButtonPushed(app, event)
            global originalImage
            global smallImage

            [rows, cols, ~] = size(originalImage);
            smallImage = originalImage(:, 1:cols/2, :);
            imshow(smallImage,'Parent',app.UIAxes2);
        end

        % Button pushed function: CompareSIFTButton
        function CompareSIFTButtonPushed(app, event)
            global originalImage
            global smallImage
            
            originalGray = rgb2gray(originalImage);
            smallGray = rgb2gray(smallImage);
            
            % Detect SIFT features
            pointsOriginal = detectSIFTFeatures(originalGray);
            pointsSmall = detectSIFTFeatures(smallGray);
            
            % Extract features
            [featuresOriginal, validPointsOriginal] = extractFeatures(originalGray, pointsOriginal);
            [featuresSmall, validPointsSmall] = extractFeatures(smallGray, pointsSmall);
            
            % Match features
            indexPairs = matchFeatures(featuresOriginal, featuresSmall);
            
            originalCordinates = pointsOriginal.Location;
            markedOriginalImage = insertMarker(originalImage,round(originalCordinates),"x-mark");
            imshow(markedOriginalImage,'Parent',app.UIAxes);

            smallCordinates = pointsSmall.Location;
            markedSmallImage = insertMarker(smallImage,round(smallCordinates),"x-mark");
            imshow(markedSmallImage,'Parent',app.UIAxes2);
            
            matchedPointsOriginal = validPointsOriginal(indexPairs(:,1),:);
            matchedPointsSmall = validPointsSmall(indexPairs(:,2),:);
            
            
            showMatchedFeatures(originalImage,smallImage,matchedPointsOriginal,matchedPointsSmall,'montage','Parent',app.UIAxes3);


        end

        % Button pushed function: CompareFREAKButton
        function CompareFREAKButtonPushed(app, event)
            
            global originalImage
            global smallImage
            
            originalGray = rgb2gray(originalImage);
            smallGray = rgb2gray(smallImage);
            
            % Detect SIFT features
            pointsOriginal = detectHarrisFeatures(originalGray);
            pointsSmall = detectHarrisFeatures(smallGray);
            
            % Extract features
            [featuresOriginal, validPointsOriginal] = extractFeatures(originalGray, pointsOriginal,'Method','FREAK');
            [featuresSmall, validPointsSmall] = extractFeatures(smallGray, pointsSmall,'Method','FREAK');
            
            % Match features
            indexPairs = matchFeatures(featuresOriginal, featuresSmall);
            
            originalCordinates = pointsOriginal.Location;
            markedOriginalImage = insertMarker(originalImage,round(originalCordinates),"x-mark");
            imshow(markedOriginalImage,'Parent',app.UIAxes);

            smallCordinates = pointsSmall.Location;
            markedSmallImage = insertMarker(smallImage,round(smallCordinates),"x-mark");
            imshow(markedSmallImage,'Parent',app.UIAxes2);
            
            matchedPointsOriginal = validPointsOriginal(indexPairs(:,1),:);
            matchedPointsSmall = validPointsSmall(indexPairs(:,2),:);
            
            
            showMatchedFeatures(originalImage,smallImage,matchedPointsOriginal,matchedPointsSmall,'montage','Parent',app.UIAxes3);


        end

        % Button pushed function: CompareSURFButton
        function CompareSURFButtonPushed(app, event)
            global originalImage
            global smallImage
            
            originalGray = rgb2gray(originalImage);
            smallGray = rgb2gray(smallImage);
            
            % Detect SIFT features
            pointsOriginal = detectSURFFeatures(originalGray);
            pointsSmall = detectSURFFeatures(smallGray);
            
            % Extract features
            [featuresOriginal, validPointsOriginal] = extractFeatures(originalGray, pointsOriginal);
            [featuresSmall, validPointsSmall] = extractFeatures(smallGray, pointsSmall);
            
            % Match features
            indexPairs = matchFeatures(featuresOriginal, featuresSmall);
            
            originalCordinates = pointsOriginal.Location;
            markedOriginalImage = insertMarker(originalImage,round(originalCordinates),"x-mark");
            imshow(markedOriginalImage,'Parent',app.UIAxes);

            smallCordinates = pointsSmall.Location;
            markedSmallImage = insertMarker(smallImage,round(smallCordinates),"x-mark");
            imshow(markedSmallImage,'Parent',app.UIAxes2);
            
            matchedPointsOriginal = validPointsOriginal(indexPairs(:,1),:);
            matchedPointsSmall = validPointsSmall(indexPairs(:,2),:);
            
            
            showMatchedFeatures(originalImage,smallImage,matchedPointsOriginal,matchedPointsSmall,'montage','Parent',app.UIAxes3);


        end

        % Button pushed function: CompareMSERFButton
        function CompareMSERFButtonPushed(app, event)
                        global originalImage
            global smallImage
            
            originalGray = rgb2gray(originalImage);
            smallGray = rgb2gray(smallImage);
            
            % Detect SIFT features
            pointsOriginal = detectMSERFeatures(originalGray);
            pointsSmall = detectMSERFeatures(smallGray);
            
            % Extract features
            [featuresOriginal, validPointsOriginal] = extractFeatures(originalGray, pointsOriginal);
            [featuresSmall, validPointsSmall] = extractFeatures(smallGray, pointsSmall);
            
            % Match features
            indexPairs = matchFeatures(featuresOriginal, featuresSmall);
            
            originalCordinates = pointsOriginal.Location;
            markedOriginalImage = insertMarker(originalImage,round(originalCordinates),"x-mark");
            imshow(markedOriginalImage,'Parent',app.UIAxes);

            smallCordinates = pointsSmall.Location;
            markedSmallImage = insertMarker(smallImage,round(smallCordinates),"x-mark");
            imshow(markedSmallImage,'Parent',app.UIAxes2);
            
            matchedPointsOriginal = validPointsOriginal(indexPairs(:,1),:);
            matchedPointsSmall = validPointsSmall(indexPairs(:,2),:);
            
            
            showMatchedFeatures(originalImage,smallImage,matchedPointsOriginal,matchedPointsSmall,'montage','Parent',app.UIAxes3);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenSmallButton = uibutton(app.UIFigure, 'push');
            app.OpenSmallButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSmallButtonPushed, true);
            app.OpenSmallButton.Text = 'Open Small';
            app.CompareSIFTButton = uibutton(app.UIFigure, 'push');
            app.CompareSIFTButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSIFTButtonPushed, true);
            app.CompareSIFTButton.Text = 'Compare SIFT';
            app.CompareFREAKButton = uibutton(app.UIFigure, 'push');
            app.CompareFREAKButton.ButtonPushedFcn = createCallbackFcn(app, @CompareFREAKButtonPushed, true);
            app.CompareFREAKButton.Text = 'Compare FREAK';
            app.CompareSURFButton = uibutton(app.UIFigure, 'push');
            app.CompareSURFButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSURFButtonPushed, true);
            app.CompareSURFButton.Text = 'Compare SURF';
            app.CompareMSERFButton = uibutton(app.UIFigure, 'push');
            app.CompareMSERFButton.ButtonPushedFcn = createCallbackFcn(app, @CompareMSERFButtonPushed, true);
            app.CompareMSERFButton.Text = 'Compare MSERF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab07

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end