classdef Lab08 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SegmentationButton  matlab.ui.control.Button
        Sampling3Button     matlab.ui.control.Button
        Sampling2Button     matlab.ui.control.Button
        Sampling1Button     matlab.ui.control.Button
        OpenButton          matlab.ui.control.Button
        CloseButton         matlab.ui.control.Button
        UIAxes5             matlab.ui.control.UIAxes
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global originalImage
            [file,path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files'}, 'Select Image File');
            originalImage = imread([path,file]);
            imshow(originalImage,'Parent',app.UIAxes);
        end

        % Button pushed function: Sampling1Button
        function Sampling1ButtonPushed(app, event)
global originalImage
global template1

pixels = impixel(originalImage);
close();


redChannel = pixels(:, 1);
greenChannel = pixels(:, 2);
blueChannel = pixels(:, 3);

minR = min(redChannel);
maxR = max(redChannel);
minG = min(greenChannel);
maxG = max(greenChannel);
minB = min(blueChannel);
maxB = max(blueChannel);


template1 = zeros(size(originalImage, 1), size(originalImage, 2));

template1((originalImage(:,:,1) >= minR & originalImage(:,:,1) <= maxR) & ...
         (originalImage(:,:,2) >= minG & originalImage(:,:,2) <= maxG) & ...
         (originalImage(:,:,3) >= minB & originalImage(:,:,3) <= maxB)) = 255;

imshow(template1,'Parent',app.UIAxes3);

        end

        % Button pushed function: Sampling2Button
        function Sampling2ButtonPushed(app, event)
            global originalImage
global template2

pixels = impixel(originalImage);
close();


redChannel = pixels(:, 1);
greenChannel = pixels(:, 2);
blueChannel = pixels(:, 3);

minR = min(redChannel);
maxR = max(redChannel);
minG = min(greenChannel);
maxG = max(greenChannel);
minB = min(blueChannel);
maxB = max(blueChannel);


template2 = zeros(size(originalImage, 1), size(originalImage, 2));

template2((originalImage(:,:,1) >= minR & originalImage(:,:,1) <= maxR) & ...
         (originalImage(:,:,2) >= minG & originalImage(:,:,2) <= maxG) & ...
         (originalImage(:,:,3) >= minB & originalImage(:,:,3) <= maxB)) = 255;

imshow(template2,'Parent',app.UIAxes4);
        end

        % Button pushed function: Sampling3Button
        function Sampling3ButtonPushed(app, event)
            global originalImage
global template3

pixels = impixel(originalImage);
close();


redChannel = pixels(:, 1);
greenChannel = pixels(:, 2);
blueChannel = pixels(:, 3);

minR = min(redChannel);
maxR = max(redChannel);
minG = min(greenChannel);
maxG = max(greenChannel);
minB = min(blueChannel);
maxB = max(blueChannel);


template3 = zeros(size(originalImage, 1), size(originalImage, 2));

template3((originalImage(:,:,1) >= minR & originalImage(:,:,1) <= maxR) & ...
         (originalImage(:,:,2) >= minG & originalImage(:,:,2) <= maxG) & ...
         (originalImage(:,:,3) >= minB & originalImage(:,:,3) <= maxB)) = 255;

imshow(template3,'Parent',app.UIAxes5);
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            global originalImage
            global template1
            global template2
            global template3

            segmentedImage = originalImage;
            
            segmentedImage(:, :, 1) = 255;
            segmentedImage(:, :, 2) = 255;  
            segmentedImage(:, :, 3) = 0;   

            for i = 1:size(originalImage,1)
                for j = 1:size(originalImage,2)
                    if template1(i,j) == 255
                        segmentedImage(i,j,1) = 255;
                        segmentedImage(i,j,2) = 0;
                        segmentedImage(i,j,3) = 0;
                    end
                end
            end

            for i = 1:size(originalImage,1)
                for j = 1:size(originalImage,2)
                    if template2(i,j) == 255
                        segmentedImage(i,j,2) = 255;
                        segmentedImage(i,j,1) = 0;
                        segmentedImage(i,j,3) = 0;
                    end
                end
            end

            for i = 1:size(originalImage,1)
                for j = 1:size(originalImage,2)
                    if template3(i,j) == 255
                        segmentedImage(i,j,3) = 255;
                        segmentedImage(i,j,1) = 0;
                        segmentedImage(i,j,2) = 0;
                    end
                end
            end

            imshow(segmentedImage,'Parent',app.UIAxes2);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.Sampling1Button = uibutton(app.UIFigure, 'push');
            app.Sampling1Button.ButtonPushedFcn = createCallbackFcn(app, @Sampling1ButtonPushed, true);
            app.Sampling1Button.Text = 'Sampling 1';
            app.Sampling2Button = uibutton(app.UIFigure, 'push');
            app.Sampling2Button.ButtonPushedFcn = createCallbackFcn(app, @Sampling2ButtonPushed, true);
            app.Sampling2Button.Text = 'Sampling 2';
            app.Sampling3Button = uibutton(app.UIFigure, 'push');
            app.Sampling3Button.ButtonPushedFcn = createCallbackFcn(app, @Sampling3ButtonPushed, true);
            app.Sampling3Button.Text = 'Sampling 3 ';
            app.SegmentationButton = uibutton(app.UIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab08

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end